classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HSMSim                         matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        TankconditionsTab              matlab.ui.container.Tab
        ChooseInputDropDown            matlab.ui.control.DropDown
        ChooseInputDropDownLabel       matlab.ui.control.Label
        ValueEditField                 matlab.ui.control.NumericEditField
        ValueEditFieldLabel            matlab.ui.control.Label
        ThisischillinghereforthevolumecalculationsfornowLabel  matlab.ui.control.Label
        InfoBoxInput                   matlab.ui.control.Image
        SpecificGravityEditField       matlab.ui.control.NumericEditField
        SpecificGravityEditFieldLabel  matlab.ui.control.Label
        InfoBoxSpecGrav                matlab.ui.control.Image
        WidthLabel                     matlab.ui.control.Label
        EllipsoidEndRadiusEditField    matlab.ui.control.NumericEditField
        EllipsoidEndRadiusLabel        matlab.ui.control.Label
        TotallLengthEditField          matlab.ui.control.NumericEditField
        TotalLengthLabel               matlab.ui.control.Label
        WidthEditField                 matlab.ui.control.NumericEditField
        CalculateButton                matlab.ui.control.Button
        UoAImage_tank                  matlab.ui.control.Image
        Lamp                           matlab.ui.control.Lamp
        StressMPaEditField             matlab.ui.control.NumericEditField
        StressMPaEditFieldLabel        matlab.ui.control.Label
        VolumelitersEditField          matlab.ui.control.NumericEditField
        VolumelitersEditFieldLabel     matlab.ui.control.Label
        StressMPaGaugeLabel            matlab.ui.control.Label
        StressMPaGauge                 matlab.ui.control.Gauge
        VolumeGaugeLabel               matlab.ui.control.Label
        VolumeGauge                    matlab.ui.control.LinearGauge
        FBGStrainEditField             matlab.ui.control.NumericEditField
        FBGStrainEditFieldLabel        matlab.ui.control.Label
        TotalMasskgEditField           matlab.ui.control.NumericEditField
        TotalMasskgEditFieldLabel      matlab.ui.control.Label
        FactorofSafetyEditField        matlab.ui.control.NumericEditField
        FactorofSafetyEditFieldLabel   matlab.ui.control.Label
        StatusTextArea                 matlab.ui.control.TextArea
        StatusTextAreaLabel            matlab.ui.control.Label
        HeightEditField                matlab.ui.control.NumericEditField
        HeightmEditFieldLabel          matlab.ui.control.Label
        DefaultTankCheckBox            matlab.ui.control.CheckBox
        NewTankCheckBox                matlab.ui.control.CheckBox
        UploadButtonStress             matlab.ui.control.Button
        StressResponseLabel            matlab.ui.control.Label
        UploadButtonFBG                matlab.ui.control.Button
        FBGResponseLabel               matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        FBGResponseSimulationTab       matlab.ui.container.Tab
        ThermalCoeffEditField          matlab.ui.control.NumericEditField
        ThermalCoeffLabel              matlab.ui.control.Label
        StrainEditField                matlab.ui.control.NumericEditField
        StrainLabel                    matlab.ui.control.Label
        DTempEditField                 matlab.ui.control.NumericEditField
        DTempLabel                     matlab.ui.control.Label
        PeCoeffEditField               matlab.ui.control.NumericEditField
        PECoeffLabel                   matlab.ui.control.Label
        GratingLengthEditField         matlab.ui.control.NumericEditField
        GratingLengthLabel             matlab.ui.control.Label
        GratingPeriodEditField         matlab.ui.control.NumericEditField
        GratingPeriodLabel             matlab.ui.control.Label
        DRefIndexEditField             matlab.ui.control.NumericEditField
        DRefIndexLabel                 matlab.ui.control.Label
        CladdingIndexEditField         matlab.ui.control.NumericEditField
        CladdingIndexLabel             matlab.ui.control.Label
        InitialconditionsLabel         matlab.ui.control.Label
        RefIndexEditField              matlab.ui.control.NumericEditField
        RefIndexLabel                  matlab.ui.control.Label
        SimulateButton                 matlab.ui.control.Button
        UoAImage                       matlab.ui.control.Image
        FBGRefGraph                    matlab.ui.control.UIAxes
        FBGStrainedGraph               matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        fbgFile             %file handle for the FBG response
        fbgPath             %Path to the file with FBG if needed
        stressFile          %file handle for the stress response
        stressPath          %path to the file for stress response
        peakWavelength      %peak wavelength found in the file
        safe                %boolean to determine whether the container is safe or not
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NewTankCheckBox
        function NewTankCheckBoxValueChanged(app, event)
            value = app.NewTankCheckBox.Value;
            app.DefaultTankCheckBox.Value = ~value;
            app.HeightEditField.Enable = value;
            app.HeightmEditFieldLabel.Enable = value;
            app.EllipsoidEndRadiusEditField.Enable = value;
            app.EllipsoidEndRadiusLabel.Enable = value;
            app.TotallLengthEditField.Enable = value;
            app.TotalLengthLabel.Enable = value;
            app.WidthEditField.Enable = value;
            app.WidthLabel.Enable = value;
            app.StressResponseLabel.Enable = value;
            %app.FBGResponseLabel.Enable = value;
           % app.UploadButtonFBG.Enable = value;
            app.UploadButtonStress.Enable = value;

        end

        % Value changed function: DefaultTankCheckBox
        function DefaultTankCheckBoxValueChanged(app, event)
            value = ~app.DefaultTankCheckBox.Value;
            app.NewTankCheckBox.Value = value;
            app.HeightEditField.Enable = value;
            app.HeightmEditFieldLabel.Enable = value;
            app.EllipsoidEndRadiusEditField.Enable = value;
            app.EllipsoidEndRadiusLabel.Enable = value;
            app.TotallLengthEditField.Enable = value;
            app.TotalLengthLabel.Enable = value;
            app.WidthEditField.Enable = value;
            app.WidthLabel.Enable = value;
            app.StressResponseLabel.Enable = value;
            %app.FBGResponseLabel.Enable = value;
           % app.UploadButtonFBG.Enable = value;
            app.UploadButtonStress.Enable = value;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            if(app.fbgFile ~= 0)
                openFile;
            else
                errordlg("FBG Response file not loaded!","File error"); 
                return;
            end
            %Calculate volume

            simulate;
        end

        % Button pushed function: UploadButtonStress
        function UploadButtonStressPushed(app, event)
            app.stressFile = uigetfile({'*.*'});
        end

        % Button pushed function: UploadButtonFBG
        function UploadButtonFBGPushed(app, event)
            [app.fbgFile, app.fbgPath] = uigetfile({'*.*'});
            %fbgFile = uigetfile(".asc")
        end

        % Value changed function: VolumelitersEditField
        function VolumelitersEditFieldValueChanged(app, event)

        end

        % Image clicked function: InfoBoxSpecGrav
        function InfoBoxSpecGravClicked(app, event)
            msgbox(['Specific gravity is the ratio of the density of a substance compared to' ...
                ' some standard substance. For this application, it is water at a density of 1kg/l.' ...
                ''],'Help',"help");
        end

        % Image clicked function: InfoBoxInput
        function InfoBoxInputImageClicked(app, event)
                        msgbox(['In case volume or liquid height is chosen for a custom tank,' ...
                ' ensure that the new tank dimensions have been input into the programme for correct response.' ...
                ' I also might throw this away because it doesnt tie in rn' ...
                ''],'Help',"help");
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            transferMatrixMethod;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.HSMSim = uifigure('Visible', 'off');
            app.HSMSim.Name = 'FBG HSM Simulator';
            app.TabGroup = uitabgroup(app.HSMSim);
            app.TankconditionsTab = uitab(app.TabGroup);
            app.TankconditionsTab.Title = 'Tank conditions';
            app.UIAxes = uiaxes(app.TankconditionsTab);
            title(app.UIAxes, 'FBG Sensor')
            xlabel(app.UIAxes, 'Wavelength (nm)')
            ylabel(app.UIAxes, 'Arbitrary Unit')
            zlabel(app.UIAxes, 'Z')
            app.FBGResponseLabel = uilabel(app.TankconditionsTab);
            app.FBGResponseLabel.Text = 'FBG Response';
            app.UploadButtonFBG = uibutton(app.TankconditionsTab, 'push');
            app.UploadButtonFBG.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonFBGPushed, true);
            app.UploadButtonFBG.Icon = fullfile(pathToMLAPP, 'images', 'upload.jpg');
            app.UploadButtonFBG.Text = 'Upload';
            app.StressResponseLabel = uilabel(app.TankconditionsTab);
            app.StressResponseLabel.Enable = 'off';
            app.StressResponseLabel.Text = 'Stress Response';
            app.UploadButtonStress = uibutton(app.TankconditionsTab, 'push');
            app.UploadButtonStress.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonStressPushed, true);
            app.UploadButtonStress.Icon = fullfile(pathToMLAPP, 'images', 'upload.jpg');
            app.UploadButtonStress.Enable = 'off';
            app.UploadButtonStress.Text = 'Upload';
            app.NewTankCheckBox = uicheckbox(app.TankconditionsTab);
            app.NewTankCheckBox.ValueChangedFcn = createCallbackFcn(app, @NewTankCheckBoxValueChanged, true);
            app.NewTankCheckBox.Text = 'New Tank';
            app.DefaultTankCheckBox = uicheckbox(app.TankconditionsTab);
            app.DefaultTankCheckBox.ValueChangedFcn = createCallbackFcn(app, @DefaultTankCheckBoxValueChanged, true);
            app.DefaultTankCheckBox.Text = 'Default Tank';
            app.DefaultTankCheckBox.Value = true;
            app.HeightmEditFieldLabel = uilabel(app.TankconditionsTab);
            app.HeightmEditFieldLabel.Enable = 'off';
            app.HeightmEditFieldLabel.Text = 'Height (m)';
            app.HeightEditField = uieditfield(app.TankconditionsTab, 'numeric');
            app.HeightEditField.Limits = [0 Inf];
            app.HeightEditField.Enable = 'off';
            app.HeightEditField.Value = 2.4;
            app.StatusTextAreaLabel = uilabel(app.TankconditionsTab);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.TankconditionsTab);
            app.StatusTextArea.Editable = 'off';
            app.StatusTextArea.Value = {'Unknown.'};
            app.FactorofSafetyEditFieldLabel = uilabel(app.TankconditionsTab);
            app.FactorofSafetyEditFieldLabel.Text = 'Factor of Safety';
            app.FactorofSafetyEditField = uieditfield(app.TankconditionsTab, 'numeric');
            app.FactorofSafetyEditField.Editable = 'off';
            app.TotalMasskgEditFieldLabel = uilabel(app.TankconditionsTab);
            app.TotalMasskgEditFieldLabel.Text = 'Total Mass (kg)';
            app.TotalMasskgEditField = uieditfield(app.TankconditionsTab, 'numeric');
            app.TotalMasskgEditField.Editable = 'off';
            app.FBGStrainEditFieldLabel = uilabel(app.TankconditionsTab);
            app.FBGStrainEditFieldLabel.Text = 'FBG Strain';
            app.FBGStrainEditField = uieditfield(app.TankconditionsTab, 'numeric');
            app.FBGStrainEditField.Editable = 'off';
            app.VolumeGauge = uigauge(app.TankconditionsTab, 'linear');
            app.VolumeGaugeLabel = uilabel(app.TankconditionsTab);
            app.VolumeGaugeLabel.Text = 'Volume(%)';
            app.StressMPaGauge = uigauge(app.TankconditionsTab, 'circular');
            app.StressMPaGauge.Limits = [0 275];
            app.StressMPaGaugeLabel = uilabel(app.TankconditionsTab);
            app.StressMPaGaugeLabel.Text = 'Stress (MPa)';
            app.VolumelitersEditFieldLabel = uilabel(app.TankconditionsTab);
            app.VolumelitersEditFieldLabel.Text = 'Volume (liters)';
            app.VolumelitersEditField = uieditfield(app.TankconditionsTab, 'numeric');
            app.VolumelitersEditField.ValueChangedFcn = createCallbackFcn(app, @VolumelitersEditFieldValueChanged, true);
            app.VolumelitersEditField.Editable = 'off';
            app.StressMPaEditFieldLabel = uilabel(app.TankconditionsTab);
            app.StressMPaEditFieldLabel.Text = 'Stress(MPa)';
            app.StressMPaEditField = uieditfield(app.TankconditionsTab, 'numeric');
            app.StressMPaEditField.Editable = 'off';
            app.Lamp = uilamp(app.TankconditionsTab);
            app.UoAImage_tank = uiimage(app.TankconditionsTab);
            app.UoAImage_tank.ImageSource = fullfile(pathToMLAPP, 'images', 'Gaelic logo cmyk 6_12_18-01.png');
            app.CalculateButton = uibutton(app.TankconditionsTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.WidthEditField = uieditfield(app.TankconditionsTab, 'numeric');
            app.WidthEditField.Limits = [0 Inf];
            app.WidthEditField.Enable = 'off';
            app.WidthEditField.Value = 2.2;
            app.TotalLengthLabel = uilabel(app.TankconditionsTab);
            app.TotalLengthLabel.Enable = 'off';
            app.TotalLengthLabel.Text = 'Total length (m)';
            app.TotallLengthEditField = uieditfield(app.TankconditionsTab, 'numeric');
            app.TotallLengthEditField.Limits = [0 Inf];
            app.TotallLengthEditField.Enable = 'off';
            app.TotallLengthEditField.Value = 5.858;
            app.EllipsoidEndRadiusLabel = uilabel(app.TankconditionsTab);
            app.EllipsoidEndRadiusLabel.Enable = 'off';
            app.EllipsoidEndRadiusLabel.Text = 'Ellipsoid end radius (m)';
            app.EllipsoidEndRadiusEditField = uieditfield(app.TankconditionsTab, 'numeric');
            app.EllipsoidEndRadiusEditField.Limits = [0 Inf];
            app.EllipsoidEndRadiusEditField.Enable = 'off';
            app.EllipsoidEndRadiusEditField.Value = 0.4;
            app.WidthLabel = uilabel(app.TankconditionsTab);
            app.WidthLabel.Enable = 'off';
            app.WidthLabel.Text = 'Width (m)';
            app.InfoBoxSpecGrav = uiimage(app.TankconditionsTab);
            app.InfoBoxSpecGrav.ImageClickedFcn = createCallbackFcn(app, @InfoBoxSpecGravClicked, true);
            app.InfoBoxSpecGrav.ImageSource = fullfile(pathToMLAPP, 'images', 'infoIcon.png');
            app.SpecificGravityEditFieldLabel = uilabel(app.TankconditionsTab);
            app.SpecificGravityEditFieldLabel.Text = 'Specific Gravity';
            app.SpecificGravityEditField = uieditfield(app.TankconditionsTab, 'numeric');
            app.SpecificGravityEditField.Limits = [0 Inf];
            app.SpecificGravityEditField.Value = 1;
            app.InfoBoxInput = uiimage(app.TankconditionsTab);
            app.InfoBoxInput.ImageClickedFcn = createCallbackFcn(app, @InfoBoxInputImageClicked, true);
            app.InfoBoxInput.ImageSource = fullfile(pathToMLAPP, 'images', 'infoIcon.png');
            app.ThisischillinghereforthevolumecalculationsfornowLabel = uilabel(app.TankconditionsTab);
            app.ThisischillinghereforthevolumecalculationsfornowLabel.Text = {'This is chilling here for the volume'; ' calculations for now, will remove at some point:'};
            app.ValueEditFieldLabel = uilabel(app.TankconditionsTab);
            app.ValueEditFieldLabel.Text = 'Value';
            app.ValueEditField = uieditfield(app.TankconditionsTab, 'numeric');
            app.ValueEditField.Limits = [0 Inf];
            app.ChooseInputDropDownLabel = uilabel(app.TankconditionsTab);
            app.ChooseInputDropDownLabel.Text = 'Choose Input';
            app.ChooseInputDropDown = uidropdown(app.TankconditionsTab);
            app.ChooseInputDropDown.Items = {'Liquid Height (mm)', 'Volume (liters)'};
            app.ChooseInputDropDown.Value = 'Liquid Height (mm)';
            app.FBGResponseSimulationTab = uitab(app.TabGroup);
            app.FBGResponseSimulationTab.Title = 'FBG Response Simulation';
            app.FBGStrainedGraph = uiaxes(app.FBGResponseSimulationTab);
            title(app.FBGStrainedGraph, 'FBG Response for strain and temperature')
            xlabel(app.FBGStrainedGraph, 'Wavelength (nm)')
            ylabel(app.FBGStrainedGraph, 'Reflectivity (R)')
            zlabel(app.FBGStrainedGraph, 'Z')
            app.FBGRefGraph = uiaxes(app.FBGResponseSimulationTab);
            title(app.FBGRefGraph, 'FBG Response, no strain/temperature change')
            xlabel(app.FBGRefGraph, 'Wavelength (nm)')
            ylabel(app.FBGRefGraph, 'Reflectivity (R)')
            zlabel(app.FBGRefGraph, 'Z')
            app.UoAImage = uiimage(app.FBGResponseSimulationTab);
            app.UoAImage.ImageSource = fullfile(pathToMLAPP, 'images', 'Gaelic logo cmyk 6_12_18-01.png');
            app.SimulateButton = uibutton(app.FBGResponseSimulationTab, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.RefIndexLabel = uilabel(app.FBGResponseSimulationTab);
            app.RefIndexLabel.Text = 'Refractive index';
            app.RefIndexEditField = uieditfield(app.FBGResponseSimulationTab, 'numeric');
            app.RefIndexEditField.Limits = [0 Inf];
            app.RefIndexEditField.Value = 1.47;
            app.InitialconditionsLabel = uilabel(app.FBGResponseSimulationTab);
            app.InitialconditionsLabel.Text = 'Initial conditions';
            app.CladdingIndexLabel = uilabel(app.FBGResponseSimulationTab);
            app.CladdingIndexLabel.Text = 'Cladding index';
            app.CladdingIndexEditField = uieditfield(app.FBGResponseSimulationTab, 'numeric');
            app.CladdingIndexEditField.Limits = [0 Inf];
            app.CladdingIndexEditField.Value = 1.457;
            app.DRefIndexLabel = uilabel(app.FBGResponseSimulationTab);
            app.DRefIndexLabel.Text = 'Change in ref. Index';
            app.DRefIndexEditField = uieditfield(app.FBGResponseSimulationTab, 'numeric');
            app.DRefIndexEditField.Limits = [0 Inf];
            app.DRefIndexEditField.Value = 0.0001;
            app.GratingPeriodLabel = uilabel(app.FBGResponseSimulationTab);
            app.GratingPeriodLabel.Text = 'Grating period (m)';
            app.GratingPeriodEditField = uieditfield(app.FBGResponseSimulationTab, 'numeric');
            app.GratingPeriodEditField.Limits = [0 Inf];
            app.GratingPeriodEditField.ValueDisplayFormat = '%11.7g';
            app.GratingPeriodEditField.Value = 5.27212e-07;
            app.GratingLengthLabel = uilabel(app.FBGResponseSimulationTab);
            app.GratingLengthLabel.Text = 'Grating length (m)';
            app.GratingLengthEditField = uieditfield(app.FBGResponseSimulationTab, 'numeric');
            app.GratingLengthEditField.Limits = [0 Inf];
            app.GratingLengthEditField.Value = 0.015;
            app.PECoeffLabel = uilabel(app.FBGResponseSimulationTab);
            app.PECoeffLabel.Text = 'Photo-elastic coeff.';
            app.PeCoeffEditField = uieditfield(app.FBGResponseSimulationTab, 'numeric');
            app.PeCoeffEditField.Limits = [0 Inf];
            app.PeCoeffEditField.Value = 0.215;
            app.DTempLabel = uilabel(app.FBGResponseSimulationTab);
            app.DTempLabel.Text = 'Change in temp.';
            app.DTempEditField = uieditfield(app.FBGResponseSimulationTab, 'numeric');
            app.DTempEditField.Limits = [-300 Inf];
            app.StrainLabel = uilabel(app.FBGResponseSimulationTab);
            app.StrainLabel.Text = 'Strain (future TODO)';
            app.StrainEditField = uieditfield(app.FBGResponseSimulationTab, 'numeric');
            app.StrainEditField.Limits = [0 Inf];
            app.StrainEditField.Value = 0.0005;
            app.ThermalCoeffLabel = uilabel(app.FBGResponseSimulationTab);
            app.ThermalCoeffLabel.Text = 'Thermal expansion coeff.';
            app.ThermalCoeffEditField = uieditfield(app.FBGResponseSimulationTab, 'numeric');
            app.ThermalCoeffEditField.Limits = [0 Inf];
            app.ThermalCoeffEditField.Value = 2.6e-06;
            % Show the figure after all components are created
            app.HSMSim.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HSMSim)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HSMSim)
        end
    end
end